classdef MotionLabHMI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        eMotion8000Tab              matlab.ui.container.Tab
        ActuatorPositionsPanel_2    matlab.ui.container.Panel
        PositionGauge_13            matlab.ui.control.LinearGauge
        PositionGauge_12            matlab.ui.control.LinearGauge
        PositionGauge_11            matlab.ui.control.LinearGauge
        PositionGauge_10            matlab.ui.control.LinearGauge
        PositionGauge_9             matlab.ui.control.LinearGauge
        PositionGauge_8             matlab.ui.control.LinearGauge
        SinusoidalSignalGeneratorPanel_2  matlab.ui.container.Panel
        SimulationProgress_2        simulink.ui.control.SimulationProgress
        SimulationControls_2        simulink.ui.control.SimulationControls
        AmplitudeEditFieldLabel_14  matlab.ui.control.Label
        AmplitudeEditFieldLabel_13  matlab.ui.control.Label
        AmplitudeEditFieldLabel_12  matlab.ui.control.Label
        AmplitudeEditFieldLabel_11  matlab.ui.control.Label
        AmplitudeEditFieldLabel_10  matlab.ui.control.Label
        AmplitudeEditFieldLabel_9   matlab.ui.control.Label
        AmplitudeEditField5_2       matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel_8   matlab.ui.control.Label
        PhaseEditField_2            matlab.ui.control.NumericEditField
        PhaseEditFieldLabel_2       matlab.ui.control.Label
        AmplitudeEditField_45       matlab.ui.control.NumericEditField
        AmplitudeEditField_44       matlab.ui.control.NumericEditField
        AmplitudeEditField_43       matlab.ui.control.NumericEditField
        AmplitudeEditField_42       matlab.ui.control.NumericEditField
        AmplitudeEditField_41       matlab.ui.control.NumericEditField
        AmplitudeEditField_40       matlab.ui.control.NumericEditField
        AmplitudeEditField_39       matlab.ui.control.NumericEditField
        AmplitudeEditField_38       matlab.ui.control.NumericEditField
        AmplitudeEditField_37       matlab.ui.control.NumericEditField
        AmplitudeEditField_36       matlab.ui.control.NumericEditField
        AmplitudeEditField_35       matlab.ui.control.NumericEditField
        AmplitudeEditField_34       matlab.ui.control.NumericEditField
        OffsetEditField_2           matlab.ui.control.NumericEditField
        OffsetEditFieldLabel_2      matlab.ui.control.Label
        ApplyButton_2               matlab.ui.control.Button
        AmplitudeEditField_33       matlab.ui.control.NumericEditField
        AmplitudeEditField_32       matlab.ui.control.NumericEditField
        AmplitudeEditField_31       matlab.ui.control.NumericEditField
        AmplitudeEditField_30       matlab.ui.control.NumericEditField
        AmplitudeEditField_29       matlab.ui.control.NumericEditField
        AmplitudeEditField_28       matlab.ui.control.NumericEditField
        AmplitudeEditField_27       matlab.ui.control.NumericEditField
        AmplitudeEditField_26       matlab.ui.control.NumericEditField
        FrequencyEditField_2        matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel_2   matlab.ui.control.Label
        eMotion1500Tab              matlab.ui.container.Tab
        ActuatorPositionsPanel_3    matlab.ui.container.Panel
        PositionGauge_19            matlab.ui.control.LinearGauge
        PositionGauge_18            matlab.ui.control.LinearGauge
        PositionGauge_17            matlab.ui.control.LinearGauge
        PositionGauge_16            matlab.ui.control.LinearGauge
        PositionGauge_15            matlab.ui.control.LinearGauge
        PositionGauge_14            matlab.ui.control.LinearGauge
        SinusoidalSignalGeneratorPanel_3  matlab.ui.container.Panel
        SimulationProgress_3        simulink.ui.control.SimulationProgress
        SimulationControls_3        simulink.ui.control.SimulationControls
        AmplitudeEditFieldLabel_21  matlab.ui.control.Label
        AmplitudeEditFieldLabel_20  matlab.ui.control.Label
        AmplitudeEditFieldLabel_19  matlab.ui.control.Label
        AmplitudeEditFieldLabel_18  matlab.ui.control.Label
        AmplitudeEditFieldLabel_17  matlab.ui.control.Label
        AmplitudeEditFieldLabel_16  matlab.ui.control.Label
        AmplitudeEditField5_3       matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel_15  matlab.ui.control.Label
        PhaseEditField_3            matlab.ui.control.NumericEditField
        PhaseEditFieldLabel_3       matlab.ui.control.Label
        AmplitudeEditField_65       matlab.ui.control.NumericEditField
        AmplitudeEditField_64       matlab.ui.control.NumericEditField
        AmplitudeEditField_63       matlab.ui.control.NumericEditField
        AmplitudeEditField_62       matlab.ui.control.NumericEditField
        AmplitudeEditField_61       matlab.ui.control.NumericEditField
        AmplitudeEditField_60       matlab.ui.control.NumericEditField
        AmplitudeEditField_59       matlab.ui.control.NumericEditField
        AmplitudeEditField_58       matlab.ui.control.NumericEditField
        AmplitudeEditField_57       matlab.ui.control.NumericEditField
        AmplitudeEditField_56       matlab.ui.control.NumericEditField
        AmplitudeEditField_55       matlab.ui.control.NumericEditField
        AmplitudeEditField_54       matlab.ui.control.NumericEditField
        OffsetEditField_3           matlab.ui.control.NumericEditField
        OffsetEditFieldLabel_3      matlab.ui.control.Label
        ApplyButton_3               matlab.ui.control.Button
        AmplitudeEditField_53       matlab.ui.control.NumericEditField
        AmplitudeEditField_52       matlab.ui.control.NumericEditField
        AmplitudeEditField_51       matlab.ui.control.NumericEditField
        AmplitudeEditField_50       matlab.ui.control.NumericEditField
        AmplitudeEditField_49       matlab.ui.control.NumericEditField
        AmplitudeEditField_48       matlab.ui.control.NumericEditField
        AmplitudeEditField_47       matlab.ui.control.NumericEditField
        AmplitudeEditField_46       matlab.ui.control.NumericEditField
        FrequencyEditField_3        matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel_3   matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        ModelName = 'MotionLab' % Your Simulink model name
        SignalBlock = 'MotionLab/Big Stewart Platform System/Motion Control System/WorkSpaceHMIVariables' % Path to your signal generator block
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ApplyButton_2
        function ApplySettings_eMotion_8000(app, event)
            % Define parameter names and corresponding edit fields
    parameterNames = {'Amplitude', 'Frequency', 'Phase', 'Offset'};
    directions = {'Surge_em8000', 'Sway_em8000', 'Heave_em8000', 'Roll_em8000', 'Pitch_em8000', 'Yaw_em8000'};
    editFields = {
        {app.AmplitudeEditField5_2, app.FrequencyEditField_2, app.PhaseEditField_2, app.OffsetEditField_2};
        {app.AmplitudeEditField_34, app.AmplitudeEditField_36, app.AmplitudeEditField_41, app.AmplitudeEditField_45};
        {app.AmplitudeEditField_35, app.AmplitudeEditField_28, app.AmplitudeEditField_29, app.AmplitudeEditField_30};
        {app.AmplitudeEditField_27, app.AmplitudeEditField_37, app.AmplitudeEditField_42, app.AmplitudeEditField_31};
        {app.AmplitudeEditField_26, app.AmplitudeEditField_39, app.AmplitudeEditField_43, app.AmplitudeEditField_32};
        {app.AmplitudeEditField_38, app.AmplitudeEditField_40, app.AmplitudeEditField_44, app.AmplitudeEditField_33}
    }; 

    % Loop through each direction and parameter to update variables in the base workspace
    for i = 1:length(directions)
        for j = 1:length(parameterNames)
            variableName = strcat(parameterNames{j}, directions{i});
            variableValue = editFields{i}{j}.Value;
            assignin('base', variableName, variableValue);
            % Debugging: Display the updated values
            disp(['Assigned ', variableName, ' = ', num2str(variableValue)]);
        end
    end
            % Optionally, run the simulation
            % sim(app.ModelName);
            %xATest = app.AmplitudeEditField5.Value
        end

        % Button pushed function: ApplyButton_3
        function ApplySettings_eMotion_1500(app, event)
            % Define parameter names and corresponding edit fields
    em1500.parameterNames = {'Amplitude', 'Frequency', 'Phase', 'Offset'};
    em1500.directions = {'Surge_em1500', 'Sway_em1500', 'Heave_em1500', 'Roll_em1500', 'Pitch_em1500', 'Yaw_em1500'};
    em1500.editFields = {
        {app.AmplitudeEditField5_3, app.FrequencyEditField_3, app.PhaseEditField_3, app.OffsetEditField_3};
        {app.AmplitudeEditField_54, app.AmplitudeEditField_56, app.AmplitudeEditField_61, app.AmplitudeEditField_65};
        {app.AmplitudeEditField_55, app.AmplitudeEditField_48, app.AmplitudeEditField_49, app.AmplitudeEditField_50};
        {app.AmplitudeEditField_47, app.AmplitudeEditField_57, app.AmplitudeEditField_62, app.AmplitudeEditField_51};
        {app.AmplitudeEditField_46, app.AmplitudeEditField_59, app.AmplitudeEditField_63, app.AmplitudeEditField_52};
        {app.AmplitudeEditField_58, app.AmplitudeEditField_60, app.AmplitudeEditField_64, app.AmplitudeEditField_53}
    }; 

    % Loop through each direction and parameter to update variables in the base workspace
    for i = 1:length(em1500.directions)
        for j = 1:length(em1500.parameterNames)
            em1500.variableName = strcat(em1500.parameterNames{j}, em1500.directions{i});
            em1500.variableValue = em1500.editFields{i}{j}.Value;
            assignin('base', em1500.variableName, em1500.variableValue);
            % Debugging: Display the updated values
            disp(['Assigned ', em1500.variableName, ' = ', num2str(em1500.variableValue)]);
        end
    end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.eMotion8000Tab = uitab(app.TabGroup);
            app.eMotion8000Tab.Title = 'eMotion-8000';
            app.SinusoidalSignalGeneratorPanel_2 = uipanel(app.eMotion8000Tab);
            app.SinusoidalSignalGeneratorPanel_2.Title = 'Sinusoidal Signal Generator';
            app.FrequencyEditFieldLabel_2 = uilabel(app.SinusoidalSignalGeneratorPanel_2);
            app.FrequencyEditFieldLabel_2.Text = 'Frequency';
            app.FrequencyEditField_2 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_26 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_27 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_28 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_29 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_30 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_31 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_32 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_33 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.ApplyButton_2 = uibutton(app.SinusoidalSignalGeneratorPanel_2, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplySettings_eMotion_8000, true);
            app.ApplyButton_2.Text = 'Apply';
            app.OffsetEditFieldLabel_2 = uilabel(app.SinusoidalSignalGeneratorPanel_2);
            app.OffsetEditFieldLabel_2.Text = 'Offset';
            app.OffsetEditField_2 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_34 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_35 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_36 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_37 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_38 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_39 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_40 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_41 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_42 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_43 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_44 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField_45 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.PhaseEditFieldLabel_2 = uilabel(app.SinusoidalSignalGeneratorPanel_2);
            app.PhaseEditFieldLabel_2.Text = 'Phase';
            app.PhaseEditField_2 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditFieldLabel_8 = uilabel(app.SinusoidalSignalGeneratorPanel_2);
            app.AmplitudeEditFieldLabel_8.Text = 'Amplitude';
            app.AmplitudeEditField5_2 = uieditfield(app.SinusoidalSignalGeneratorPanel_2, 'numeric');
            app.AmplitudeEditField5_2.Tag = 'AmplitudeSurgeField';
            app.AmplitudeEditFieldLabel_9 = uilabel(app.SinusoidalSignalGeneratorPanel_2);
            app.AmplitudeEditFieldLabel_9.Text = 'Surge';
            app.AmplitudeEditFieldLabel_10 = uilabel(app.SinusoidalSignalGeneratorPanel_2);
            app.AmplitudeEditFieldLabel_10.Text = 'Sway';
            app.AmplitudeEditFieldLabel_11 = uilabel(app.SinusoidalSignalGeneratorPanel_2);
            app.AmplitudeEditFieldLabel_11.Text = 'Heave';
            app.AmplitudeEditFieldLabel_12 = uilabel(app.SinusoidalSignalGeneratorPanel_2);
            app.AmplitudeEditFieldLabel_12.Text = 'Roll';
            app.AmplitudeEditFieldLabel_13 = uilabel(app.SinusoidalSignalGeneratorPanel_2);
            app.AmplitudeEditFieldLabel_13.Text = 'Pitch';
            app.AmplitudeEditFieldLabel_14 = uilabel(app.SinusoidalSignalGeneratorPanel_2);
            app.AmplitudeEditFieldLabel_14.Text = 'Yaw';
            app.SimulationControls_2 = uisimcontrols(app.SinusoidalSignalGeneratorPanel_2);
            app.SimulationControls_2.Simulation = app.Simulation;
            app.SimulationProgress_2 = uisimprogress(app.SinusoidalSignalGeneratorPanel_2);
            app.SimulationProgress_2.Simulation = app.Simulation;
            app.ActuatorPositionsPanel_2 = uipanel(app.eMotion8000Tab);
            app.ActuatorPositionsPanel_2.Title = 'Actuator Positions';
            app.PositionGauge_8 = uigauge(app.ActuatorPositionsPanel_2, 'linear');
            app.PositionGauge_8.Limits = [0 2];
            app.PositionGauge_8.Tag = 'ActPos';
            app.PositionGauge_9 = uigauge(app.ActuatorPositionsPanel_2, 'linear');
            app.PositionGauge_9.Limits = [0 2];
            app.PositionGauge_9.Tag = 'ActPos';
            app.PositionGauge_10 = uigauge(app.ActuatorPositionsPanel_2, 'linear');
            app.PositionGauge_10.Limits = [0 2];
            app.PositionGauge_10.Tag = 'ActPos';
            app.PositionGauge_11 = uigauge(app.ActuatorPositionsPanel_2, 'linear');
            app.PositionGauge_11.Limits = [0 2];
            app.PositionGauge_11.Tag = 'ActPos';
            app.PositionGauge_12 = uigauge(app.ActuatorPositionsPanel_2, 'linear');
            app.PositionGauge_12.Limits = [0 2];
            app.PositionGauge_12.Tag = 'ActPos';
            app.PositionGauge_13 = uigauge(app.ActuatorPositionsPanel_2, 'linear');
            app.PositionGauge_13.Limits = [0 2];
            app.PositionGauge_13.Tag = 'ActPos';
            app.eMotion1500Tab = uitab(app.TabGroup);
            app.eMotion1500Tab.Title = 'eMotion-1500';
            app.SinusoidalSignalGeneratorPanel_3 = uipanel(app.eMotion1500Tab);
            app.SinusoidalSignalGeneratorPanel_3.Title = 'Sinusoidal Signal Generator';
            app.FrequencyEditFieldLabel_3 = uilabel(app.SinusoidalSignalGeneratorPanel_3);
            app.FrequencyEditFieldLabel_3.Text = 'Frequency';
            app.FrequencyEditField_3 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_46 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_47 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_48 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_49 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_50 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_51 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_52 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_53 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.ApplyButton_3 = uibutton(app.SinusoidalSignalGeneratorPanel_3, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplySettings_eMotion_1500, true);
            app.ApplyButton_3.Text = 'Apply';
            app.OffsetEditFieldLabel_3 = uilabel(app.SinusoidalSignalGeneratorPanel_3);
            app.OffsetEditFieldLabel_3.Text = 'Offset';
            app.OffsetEditField_3 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_54 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_55 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_56 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_57 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_58 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_59 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_60 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_61 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_62 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_63 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_64 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField_65 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.PhaseEditFieldLabel_3 = uilabel(app.SinusoidalSignalGeneratorPanel_3);
            app.PhaseEditFieldLabel_3.Text = 'Phase';
            app.PhaseEditField_3 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditFieldLabel_15 = uilabel(app.SinusoidalSignalGeneratorPanel_3);
            app.AmplitudeEditFieldLabel_15.Text = 'Amplitude';
            app.AmplitudeEditField5_3 = uieditfield(app.SinusoidalSignalGeneratorPanel_3, 'numeric');
            app.AmplitudeEditField5_3.Tag = 'AmplitudeSurgeField';
            app.AmplitudeEditFieldLabel_16 = uilabel(app.SinusoidalSignalGeneratorPanel_3);
            app.AmplitudeEditFieldLabel_16.Text = 'Surge';
            app.AmplitudeEditFieldLabel_17 = uilabel(app.SinusoidalSignalGeneratorPanel_3);
            app.AmplitudeEditFieldLabel_17.Text = 'Sway';
            app.AmplitudeEditFieldLabel_18 = uilabel(app.SinusoidalSignalGeneratorPanel_3);
            app.AmplitudeEditFieldLabel_18.Text = 'Heave';
            app.AmplitudeEditFieldLabel_19 = uilabel(app.SinusoidalSignalGeneratorPanel_3);
            app.AmplitudeEditFieldLabel_19.Text = 'Roll';
            app.AmplitudeEditFieldLabel_20 = uilabel(app.SinusoidalSignalGeneratorPanel_3);
            app.AmplitudeEditFieldLabel_20.Text = 'Pitch';
            app.AmplitudeEditFieldLabel_21 = uilabel(app.SinusoidalSignalGeneratorPanel_3);
            app.AmplitudeEditFieldLabel_21.Text = 'Yaw';
            app.SimulationControls_3 = uisimcontrols(app.SinusoidalSignalGeneratorPanel_3);
            app.SimulationControls_3.Simulation = app.Simulation;
            app.SimulationProgress_3 = uisimprogress(app.SinusoidalSignalGeneratorPanel_3);
            app.SimulationProgress_3.Simulation = app.Simulation;
            app.ActuatorPositionsPanel_3 = uipanel(app.eMotion1500Tab);
            app.ActuatorPositionsPanel_3.Title = 'Actuator Positions';
            app.PositionGauge_14 = uigauge(app.ActuatorPositionsPanel_3, 'linear');
            app.PositionGauge_14.Limits = [0 1.1];
            app.PositionGauge_14.MajorTicks = [0 0.11 0.22 0.33 0.44 0.55 0.66 0.77 0.88 0.99 1.1];
            app.PositionGauge_14.MinorTicks = [0 0.055 0.11 0.165 0.22 0.275 0.33 0.385 0.44 0.495 0.55 0.605 0.66 0.715 0.77 0.825 0.88 0.935 0.99 1.045];
            app.PositionGauge_14.Tag = 'ActPos';
            app.PositionGauge_15 = uigauge(app.ActuatorPositionsPanel_3, 'linear');
            app.PositionGauge_15.Limits = [0 1.1];
            app.PositionGauge_15.MajorTicks = [0 0.11 0.22 0.33 0.44 0.55 0.66 0.77 0.88 0.99 1.1];
            app.PositionGauge_15.MinorTicks = [0 0.055 0.11 0.165 0.22 0.275 0.33 0.385 0.44 0.495 0.55 0.605 0.66 0.715 0.77 0.825 0.88 0.935 0.99 1.045];
            app.PositionGauge_15.Tag = 'ActPos';
            app.PositionGauge_16 = uigauge(app.ActuatorPositionsPanel_3, 'linear');
            app.PositionGauge_16.Limits = [0 1.1];
            app.PositionGauge_16.MajorTicks = [0 0.11 0.22 0.33 0.44 0.55 0.66 0.77 0.88 0.99 1.1];
            app.PositionGauge_16.MinorTicks = [0 0.055 0.11 0.165 0.22 0.275 0.33 0.385 0.44 0.495 0.55 0.605 0.66 0.715 0.77 0.825 0.88 0.935 0.99 1.045];
            app.PositionGauge_16.Tag = 'ActPos';
            app.PositionGauge_17 = uigauge(app.ActuatorPositionsPanel_3, 'linear');
            app.PositionGauge_17.Limits = [0 1.1];
            app.PositionGauge_17.MajorTicks = [0 0.11 0.22 0.33 0.44 0.55 0.66 0.77 0.88 0.99 1.1];
            app.PositionGauge_17.MinorTicks = [0 0.055 0.11 0.165 0.22 0.275 0.33 0.385 0.44 0.495 0.55 0.605 0.66 0.715 0.77 0.825 0.88 0.935 0.99 1.045];
            app.PositionGauge_17.Tag = 'ActPos';
            app.PositionGauge_18 = uigauge(app.ActuatorPositionsPanel_3, 'linear');
            app.PositionGauge_18.Limits = [0 1.1];
            app.PositionGauge_18.MajorTicks = [0 0.11 0.22 0.33 0.44 0.55 0.66 0.77 0.88 0.99 1.1];
            app.PositionGauge_18.MinorTicks = [0 0.055 0.11 0.165 0.22 0.275 0.33 0.385 0.44 0.495 0.55 0.605 0.66 0.715 0.77 0.825 0.88 0.935 0.99 1.045];
            app.PositionGauge_18.Tag = 'ActPos';
            app.PositionGauge_19 = uigauge(app.ActuatorPositionsPanel_3, 'linear');
            app.PositionGauge_19.Limits = [0 1.1];
            app.PositionGauge_19.MajorTicks = [0 0.11 0.22 0.33 0.44 0.55 0.66 0.77 0.88 0.99 1.1];
            app.PositionGauge_19.MinorTicks = [0 0.055 0.11 0.165 0.22 0.275 0.33 0.385 0.44 0.495 0.55 0.605 0.66 0.715 0.77 0.825 0.88 0.935 0.99 1.045];
            app.PositionGauge_19.Tag = 'ActPos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/VHT eMotion-8000 Stewart Platform/ExtractAndCombinePosAndVel/Demux6:1'], app.PositionGauge_8, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/VHT eMotion-8000 Stewart Platform/ExtractAndCombinePosAndVel/Demux6:2'], app.PositionGauge_9, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/VHT eMotion-8000 Stewart Platform/ExtractAndCombinePosAndVel/Demux6:3'], app.PositionGauge_10, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/VHT eMotion-8000 Stewart Platform/ExtractAndCombinePosAndVel/Demux6:4'], app.PositionGauge_11, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/VHT eMotion-8000 Stewart Platform/ExtractAndCombinePosAndVel/Demux6:5'], app.PositionGauge_12, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/VHT eMotion-8000 Stewart Platform/ExtractAndCombinePosAndVel/Demux6:6'], app.PositionGauge_13, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/VHT eMotion-1500 Stewart Platform/ExtractAndCombinePosAndVel1/Demux6:1'], app.PositionGauge_14, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/VHT eMotion-1500 Stewart Platform/ExtractAndCombinePosAndVel1/Demux6:2'], app.PositionGauge_15, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/VHT eMotion-1500 Stewart Platform/ExtractAndCombinePosAndVel1/Demux6:3'], app.PositionGauge_16, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/VHT eMotion-1500 Stewart Platform/ExtractAndCombinePosAndVel1/Demux6:4'], app.PositionGauge_17, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/VHT eMotion-1500 Stewart Platform/ExtractAndCombinePosAndVel1/Demux6:5'], app.PositionGauge_18, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/VHT eMotion-1500 Stewart Platform/ExtractAndCombinePosAndVel1/Demux6:6'], app.PositionGauge_19, 'Value');
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionLabHMI

            % Associate the Simulink Model
            app.Simulation = simulation('MotionLab');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end